classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MSButton                  matlab.ui.control.StateButton
        XYButton                  matlab.ui.control.StateButton
        StereoBzeEditField        matlab.ui.control.NumericEditField
        StereoBzeEditFieldLabel   matlab.ui.control.Label
        TyppijmaeMDropDown        matlab.ui.control.DropDown
        TyppijmaeMDropDownLabel   matlab.ui.control.Label
        TyppijmaeXYDropDown       matlab.ui.control.DropDown
        TyppijmaeXYDropDownLabel  matlab.ui.control.Label
        ZeslenslokySSlider        matlab.ui.control.Slider
        ZeslenslokySSliderLabel   matlab.ui.control.Label
        helSlider                 matlab.ui.control.Slider
        helSliderLabel            matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PaxXY % Polar axes
        PaxMS
        stereo_base
        sGain
        MS_type
        xyAngle
        XY_type

        ICLD_XY
        ICLD_MS
        XY_angle
        MS_angle

        ICLD_sin
        ICLD_tan

        X
        Y
        M
        S
        phiXY
        phiMS

        L
        R
        energy
        energy_name
    end
    
    methods (Access = private)
        
        function updatePlots(app)
            [app.ICLD_MS,app.MS_angle, app.phiMS, app.M, app.S, LMS, RMS] = vypocetICLD(app.MS_type, 'MS',app.sGain,  app.stereo_base, false);    
            [app.ICLD_XY, app.XY_angle, app.phiXY, app.X, app.Y, LXY, RXY] = vypocetICLD(app.XY_type,'XY', app.xyAngle, app.stereo_base, false);   
            
            if strcmpi(app.energy_name, 'ms')
                app.L = LMS;
                app.R = RMS;
            else
                app.L = LXY;
                app.R = RXY;
            end

            app.plotPolarRec();
            if app.energy; app.plotLR(app.L, app.R);
            else; app.plotICLD(); end
        end

        function [] = plotPolarRec(app)
        %% vyresleni 2D smerove charakteristiky v horizontalni rovine
            polarplot(app.PaxXY, app.phiXY,app.X);hold( app.PaxXY, 'on' );
            polarplot(app.PaxXY, app.phiXY, app.Y); hold( app.PaxXY, 'off' );
            app.PaxXY.ThetaZeroLocation = 'top';
            app.PaxXY.ThetaDir = 'clockwise';
            title(app.PaxXY, 'Poměr citlivosti XY');

            polarplot(app.PaxMS, app.phiMS, app.M); hold(app.PaxMS, 'on' );
            polarplot(app.PaxMS, app.phiMS, app.S); hold(app.PaxMS, 'off' );
            app.PaxMS.ThetaLim = [-180, 180];
            app.PaxMS.ThetaZeroLocation = 'top';
            app.PaxMS.ThetaDir = 'clockwise';
            title(app.PaxMS, 'Poměr citlivosti MS');
        end

        function [] = plotICLD(app)
        %% Plots the ICLD 
            alpha0 = app.stereo_base*pi/180;
            alpha = -alpha0:pi/180:alpha0;

            plot(app.UIAxes, app.XY_angle*180/pi, app.ICLD_XY, 'LineWidth', 2); hold( app.UIAxes, 'on' )
            plot(app.UIAxes, app.MS_angle*180/pi, app.ICLD_MS, 'LineWidth', 2);
            plot(app.UIAxes, alpha*180/pi, app.ICLD_sin, 'LineWidth', 2);
            plot(app.UIAxes, alpha*180/pi, app.ICLD_tan, 'LineWidth', 2);
            hold( app.UIAxes, 'off' )
            grid (app.UIAxes, 'on');
            xlabel(app.UIAxes, '\alpha [\circ] \rightarrow');
            ylabel(app.UIAxes, '{\itICLD} [dB] \rightarrow');
            ylim(app.UIAxes, [-30, 30]);
            xy = strcat('XY, pro úhel ', num2str(app.xyAngle), '° ');
            ms = strcat('MS, zesílení složky S = ', num2str(app.sGain));
            legend(app.UIAxes, xy, ms, 'Sinusový zákon', 'Tangentový zákon', 'Location', 'southeast', 'Interpreter','latex') 
            title(app.UIAxes, '');
        end

        function computeLaws(app)
            alpha0 = app.stereo_base*pi/180;
            alpha = -alpha0:pi/180:alpha0;

            % Sin law
            gRsquared = ((sin(alpha0)-sin(alpha)).^2) ./ (2*(sin(alpha0).^2+sin(alpha).^2));
            gLsquared = 1 - gRsquared;
            gL = sqrt(gLsquared);
            gR = sqrt(gRsquared);
            app.ICLD_sin = 20*log10(gL./gR);
            % Tan law
            gRsquared = ((tan(alpha0)-tan(alpha)).^2) ./ (2*(tan(alpha0).^2+tan(alpha).^2));
            gLsquared = 1 - gRsquared;
            gL = sqrt(gLsquared);
            gR = sqrt(gRsquared);
            app.ICLD_tan = 20*log10(gL./gR);
        end
    	
        function plotLR(app, L, R)
            alpha0 = app.stereo_base*pi/180;
            alpha = -alpha0:pi/180:alpha0;
            al0 = 180-round(alpha0*180/pi); 

            gL = L(al0:end-al0-1);
            gR = R(al0:end-al0-1);

            plot(app.UIAxes, alpha*180/pi, gL, 'b', 'LineWidth', 2);
            hold( app.UIAxes, 'on' );
            plot(app.UIAxes, alpha*180/pi, gR, 'r', 'LineWidth', 2);
            plot(app.UIAxes, alpha*180/pi, gL.^2+gR.^2, 'g', 'LineWidth', 2);
            hold( app.UIAxes, 'off' );
            grid( app.UIAxes, 'on');
            ylim(app.UIAxes, [0 max(gL.^2+gR.^2)+0.2]);
            xlabel(app.UIAxes, '\alpha [\circ] \rightarrow');
            legend(app.UIAxes, 'Left Channel', 'Right Channel', 'Energy')
            title(app.UIAxes, app.energy_name)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            %% Load initial values
            app.stereo_base = 30;
            app.sGain = 1;
            app.xyAngle = 45;
            app.XY_type= receiverType.kardioida;
            app.MS_type= receiverType.kardioida;
            
            % Fill drop down menus with enum names
            app.TyppijmaeXYDropDown.Items = cellstr(enumeration(app.XY_type));
            app.TyppijmaeMDropDown.Items = cellstr(enumeration(app.MS_type));

            % Create polar axes and position it in pixels
            app.PaxXY = polaraxes(app.UIFigure);
            app.PaxXY.Units = 'pixels';
            app.PaxXY.Position = [350 0 150 250];
            app.PaxXY.ThetaLim = [-180, 180];
            
            % Create polar axes and position it in pixels
            app.PaxMS = polaraxes(app.UIFigure);
            app.PaxMS.Units = 'pixels';
            app.PaxMS.Position = [600 0 150 250];
            
            %% Compute ICLD for initial parameters
            [app.ICLD_MS,app.MS_angle, app.phiMS, app.M, app.S] = vypocetICLD(app.MS_type, 'MS',app.sGain,  app.stereo_base, false);    
            [app.ICLD_XY, app.XY_angle, app.phiXY, app.X, app.Y] = vypocetICLD(app.XY_type,'XY', app.xyAngle, app.stereo_base, false);   
            
            app.computeLaws()
            
            %% Plot everything
            app.updatePlots();
        end

        % Value changed function: TyppijmaeXYDropDown
        function TyppijmaeXYDropDownValueChanged(app, event)
            switch app.TyppijmaeXYDropDown.Value
                case 'kardioida'
                    app.XY_type = receiverType.kardioida;
                case 'superkardioida'
                    app.XY_type = receiverType.superkardioida;                
                case 'hyperkardioida'
                    app.XY_type = receiverType.hyperkardioida;
                case 'subkardioida'
                    app.XY_type = receiverType.subkardioida;
            end
            app.updatePlots();
        end

        % Value changed function: StereoBzeEditField
        function StereoBzeEditFieldValueChanged(app, event)
            app.stereo_base = app.StereoBzeEditField.Value / 2;
            app.computeLaws();
            app.updatePlots();
        end

        % Value changed function: helSlider
        function helSliderValueChanged(app, event)
            app.xyAngle = app.helSlider.Value;
            app.updatePlots();
        end

        % Value changed function: ZeslenslokySSlider
        function ZeslenslokySSliderValueChanged(app, event)
            app.sGain = app.ZeslenslokySSlider.Value;
            app.updatePlots();
        end

        % Value changed function: TyppijmaeMDropDown
        function TyppijmaeMDropDownValueChanged(app, event)
            
            switch app.TyppijmaeMDropDown.Value
                case 'kardioida'
                    app.MS_type = receiverType.kardioida;
                case 'superkardioida'
                    app.MS_type = receiverType.superkardioida;                
                case 'hyperkardioida'
                    app.MS_type = receiverType.hyperkardioida;
                case 'subkardioida'
                    app.MS_type = receiverType.subkardioida;
            end
            app.updatePlots();
        end

        % Value changed function: XYButton
        function XYButtonValueChanged(app, event)
            switch app.XYButton.Value
                case 1
                    if app.MSButton.Value == 1
                        app.MSButton.Value = 0;
                    end

                    [app.ICLD_XY,app.XY_angle, app.phiXY, app.X, app.Y, app.L, app.R] = vypocetICLD(app.XY_type, 'XY',app.xyAngle,  app.stereo_base, false);     
                    app.energy = true;
                    app.energy_name = 'XY'; 
                case 0 
                    app.energy = false;
            end
            app.updatePlots();
        end

        % Value changed function: MSButton
        function MSButtonValueChanged(app, event)

            switch app.MSButton.Value
                case 1
                    if app.XYButton.Value == 1
                        app.XYButton.Value = 0;
                    end

                    [app.ICLD_MS,app.MS_angle, app.phiMS, app.M, app.S, app.L, app.R] = vypocetICLD(app.MS_type, 'MS',app.sGain,  app.stereo_base, false);     
                    app.energy = true;
                    app.energy_name = 'MS';                   
                case 0 
                    app.energy = false;
            end
            app.updatePlots();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'XY vs MS ICLD';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.helSliderLabel = uilabel(app.UIFigure);
            app.helSliderLabel.Text = 'Úhel';
            app.helSlider = uislider(app.UIFigure);
            app.helSlider.Limits = [0 180];
            app.helSlider.ValueChangedFcn = createCallbackFcn(app, @helSliderValueChanged, true);
            app.helSlider.Value = 45;
            app.ZeslenslokySSliderLabel = uilabel(app.UIFigure);
            app.ZeslenslokySSliderLabel.Text = 'Zesílení složky S';
            app.ZeslenslokySSlider = uislider(app.UIFigure);
            app.ZeslenslokySSlider.Limits = [0 2];
            app.ZeslenslokySSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.ZeslenslokySSlider.ValueChangedFcn = createCallbackFcn(app, @ZeslenslokySSliderValueChanged, true);
            app.ZeslenslokySSlider.Value = 1;
            app.TyppijmaeXYDropDownLabel = uilabel(app.UIFigure);
            app.TyppijmaeXYDropDownLabel.Text = 'Typ přijímače XY';
            app.TyppijmaeXYDropDown = uidropdown(app.UIFigure);
            app.TyppijmaeXYDropDown.Items = {};
            app.TyppijmaeXYDropDown.ValueChangedFcn = createCallbackFcn(app, @TyppijmaeXYDropDownValueChanged, true);
            app.TyppijmaeXYDropDown.Value = {};
            app.TyppijmaeMDropDownLabel = uilabel(app.UIFigure);
            app.TyppijmaeMDropDownLabel.Text = 'Typ přijímače M';
            app.TyppijmaeMDropDown = uidropdown(app.UIFigure);
            app.TyppijmaeMDropDown.Items = {};
            app.TyppijmaeMDropDown.ValueChangedFcn = createCallbackFcn(app, @TyppijmaeMDropDownValueChanged, true);
            app.TyppijmaeMDropDown.Value = {};
            app.StereoBzeEditFieldLabel = uilabel(app.UIFigure);
            app.StereoBzeEditFieldLabel.Text = 'Stereo Báze';
            app.StereoBzeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StereoBzeEditField.Limits = [0 180];
            app.StereoBzeEditField.ValueChangedFcn = createCallbackFcn(app, @StereoBzeEditFieldValueChanged, true);
            app.StereoBzeEditField.Value = 60;
            app.XYButton = uibutton(app.UIFigure, 'state');
            app.XYButton.ValueChangedFcn = createCallbackFcn(app, @XYButtonValueChanged, true);
            app.XYButton.Text = 'XY';
            app.MSButton = uibutton(app.UIFigure, 'state');
            app.MSButton.ValueChangedFcn = createCallbackFcn(app, @MSButtonValueChanged, true);
            app.MSButton.Text = 'MS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end